classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        RightPanel  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
    function [c] = histogram_calculation(app, I)
    b = size(I);
    I = double(I);
    c = zeros(1,256);
    for i=1 : b(1)
        for j=1 : b(2)
            for k = 0 : 255
                 if I(i,j) == k
                     c(k+1) = c(k+1) +1;
                 end
            end
        end
    end
        
    
    end
    
    % No. 1 - Bar Use Case
    function [histogram] = histogram_bar(app, I)
        histogram = histogram_calculation(app, I);
        app.bar(histogram, 'BarWidth', 1, 'FaceColor', 'b');
    end
    
    % No. 1 - Show Histogram with subplot
    function [n] = histogram_show(app, I)
        [~,~,n] = size(I);
        if n == 3
            r1 = I(:,:,1);
            g1 = I(:,:,2);
            b1 = I(:,:,3);

            % Create UIAxes
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Position = [8 470 365 298];
    
            app.UIaxes.subplot(1,3,1),histogram_bar(app, r1),title('Red');
            subplot(1,3,2),histogram_bar(app, g1),title('Green');
            subplot(1,3,3),histogram_bar(app, b1),title('Blue');
        else
            subplot(1,1,1),histogram_bar(app, I),title('Grayscale');
    
        end
    
    end    
    
    % No. 2.a - image brightening
    function [ s ] = makebright(app, r, a, b) 
       s = r*a + b; 
       s(s > 255) = 255;
       subplot(1,2,1),imshow(r),title("Original Image")
       subplot(1,2,2),imshow(s),title("Image after Transformation")
    end
    
    % No. 2.b - Transformasi log (𝑠 = 𝑐 𝑙𝑜𝑔(1 + 𝑟), 𝑐 dan r konstanta
    % parameter input dari pengguna
    function [ s ] = logTransform(app, r, c) 
       r_double = double(r)/255;
       s = c*log(1+(r_double)); 
       subplot(1,2,1),imshow(r),title("Original Image")
       subplot(1,2,2),imshow(s),title("Image after Log Transformation")
    end
    
    % No. 2.c - Transformasi pangkat (𝑠 = 𝑐𝑟^y, 𝑐 dan 𝛾 adalah parameter input dari pengguna)
    function [ s ] = powerTransform(app, r, c,y) 
        s = c*power(double(r)/255,double(y));
       subplot(1,2,1),imshow(r),title("Original Image")
       subplot(1,2,2),imshow(s),title("Image after Power Transformation")
    end
    
    % No. 2.d - Contrast Stretching
    function [ s ] = contrastStretching(app, r, rmin, rmax) 
       s = (r - rmin).*(255/(rmax - rmin)); 
       subplot(1,2,1),imshow(r),title("Original Image")
       subplot(1,2,2),imshow(s),title("Image after Power Transformation")
    end
    
    % No. 3 Histogram Equalization
    
    function [ result_image] = equalization(app, input_image) 
        
        [rows,columns,~] = size(input_image);
        result_image = uint8(zeros(rows,columns));
        pixel_number = rows*columns;
        frequency = zeros(256,1);
        pdf = zeros(256,1);
        cdf = zeros(256,1);
        cummulative = zeros(256,1);
        outpic = zeros(256,1);
        for i = 1:1:rows
            for j = 1:1:columns
                val = input_image(i,j);
                frequency(val+1) = frequency(val+1)+1;
                pdf(val+1) = frequency(val+1)/pixel_number;
            end
        end
        sum =0 ;
        %we want the 256 - 1 that's why we initailzed the intensity_level with 255
        %instead of 256
        intensity_level = 255;
        
        for i = 1:1:size(pdf)
            sum =sum +frequency(i);
            cummulative(i) = sum;
            cdf(i) = cummulative(i)/ pixel_number;
            outpic(i) = round(cdf(i) * intensity_level);
        end
        
        
        for i = 1:1:rows
            for j = 1:1:columns
                result_image(i,j) = outpic(input_image(i,j) + 1);
            end
        end
        
    end
    
    function [ result_image ] = histeq_manual(app, I) 
    
        [~,~,n] = size(I);
        if n == 3
            r1 = I(:,:,1);
            g1 = I(:,:,2);
            b1 = I(:,:,3);
    
            r = equalization(app, r1);
            g = equalization(app, g1);
            b = equalization(app, b1);
    
            result_image = cat(3,r,g,b);
            subplot(2,4,1),imshow(I),title('Original Image');
            subplot(2,4,2),histogram_bar(app, r1),title('Red Input');
            subplot(2,4,3),histogram_bar(app, g1),title('Green Input');
            subplot(2,4,4),histogram_bar(app, b1),title('Blue Input');
            subplot(2,4,5),imshow(result_image),title('After Histogram Equalization');
            subplot(2,4,6),histogram_bar(app, r),title('Red Output');
            subplot(2,4,7),histogram_bar(app, g),title('Green Putput');
            subplot(2,4,8),histogram_bar(app, b),title('Blue Output');
    
        else
    
            result_image = equalization(app, I);
            subplot(2,2,1),imshow(I),title('Original Image');
            subplot(2,2,3),imshow(result_image),title('After Histogram Equalization');
            subplot(2,2,2),histogram_bar(app, I),title('Original Image');
            subplot(2,2,4),histogram_bar(app, result_image),title('After Histogram Equalization');
    
        end
    
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {769, 769};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {378, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end