classdef gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ImageSelectorTab           matlab.ui.container.Tab
        ChooseImageDropDown        matlab.ui.control.DropDown
        ChooseImageDropDownLabel   matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        HistogramTab               matlab.ui.container.Tab
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        ImageEnhancementTab        matlab.ui.container.Tab
        HistogramEqualizationTab   matlab.ui.container.Tab
        HistogramSpecificationTab  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        selected_image % Description
        image_title % image title
    end


    methods (Access = private)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            I = imread('image/Fig0222(a)(face).tif');
            app.UIAxes2.Title.String = "Lenna Black & White"
            imshow(I, "Parent", app.UIAxes2)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title

            
            switch selectedTab
                case "Image Selector"
                    I = app.selected_image
                    
                    app.UIAxes2.Title.String = app.image_title
                    imshow(I, "Parent", app.UIAxes2)
                    
            end
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            value = app.ChooseImageDropDown.Value;

            switch value
                case "Image 1"
                    app.selected_image = imread('image/Fig0222(a)(face).tif')
                    app.image_title = "Lenna Black & White Zoomed"
                    %imshow(I, "Parent", app.UIAxes2)
                case "Image 2"
                    app.selected_image  = imread('image/Fig0630(01)(strawberries_fullcolor).tif')
                    app.image_title = "Srawberries Full Color"
                    %imshow(I, "Parent", app.UIAxes2)

                case "Image 3"
                    app.selected_image  = imread('image/Fig0320(2)(2nd_from_top).tif')
                    app.image_title = "Beans Black and Low Contrast"
                    %imshow(I, "Parent", app.UIAxes2)
                case "Image 4"
                    app.selected_image = imread('image/Fig0646(a)(lenna_original_RGB).tif')
                    app.image_title = "Lenna Full Color"
                    %imshow(I, "Parent", app.UIAxes2)

             
            end
            app.UIAxes2.Title.String = app.image_title
            imshow(app.selected_image, "Parent", app.UIAxes2)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImageSelectorTab = uitab(app.TabGroup);
            app.ImageSelectorTab.Title = 'Image Selector';
            app.UIAxes2 = uiaxes(app.ImageSelectorTab);
            title(app.UIAxes2, 'Image')
            app.UIAxes2.Visible = 'off';
            app.ChooseImageDropDownLabel = uilabel(app.ImageSelectorTab);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.ImageSelectorTab);
            app.ChooseImageDropDown.Items = {'Image 1', 'Image 2', 'Image 3', 'Image 4'};
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = 'Image 1';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes = uiaxes(app.HistogramTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.HistogramTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.HistogramTab);
            app.UIAxes_3.Visible = 'off';
            app.ImageEnhancementTab = uitab(app.TabGroup);
            app.ImageEnhancementTab.Title = 'Image Enhancement';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end